classdef FineTuning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NetworkFinetuningPanel         matlab.ui.container.Panel
        TabGroup4                      matlab.ui.container.TabGroup
        GeneralTab_4                   matlab.ui.container.Tab
        BaseModelPathEditField         matlab.ui.control.EditField
        BaseModelPathEditFieldLabel    matlab.ui.control.Label
        NewSamplePathEditField         matlab.ui.control.EditField
        NewSamplePathEditFieldLabel    matlab.ui.control.Label
        LabelEditField_2               matlab.ui.control.EditField
        LabelEditField_2Label          matlab.ui.control.Label
        StartFinetuningButton          matlab.ui.control.Button
        new_sample_loading             matlab.ui.control.Button
        base_model_loading             matlab.ui.control.Button
        AdvancedTab_4                  matlab.ui.container.Tab
        ProjectionRangeEditField       matlab.ui.control.EditField
        ProjectionRangeEditFieldLabel  matlab.ui.control.Label
        Fine_tuning_GPU_IDX            matlab.ui.control.NumericEditField
        GPU_IDXEditFieldLabel_2        matlab.ui.control.Label
    end


    properties (Access = private)
        Text_str
    end

    methods (Access = public)

        function  outPut_str(app,str)
            print_str = sprintf('%s\n', str);
            app.Text_str=horzcat(app.Text_str,print_str);
            app.StatusTextArea.Value = app.Text_str;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Fine-tuning';
            addpath ./source/fig/
            addpath ./source/psf/
            addpath ./utils/
            addpath ./DL/

            %% add initialization
        end

        % Button pushed function: base_model_loading
        function base_model_loadingButtonPushed(app, event)
            base_model_path = uigetdir('./DL','Select the path of base model for fine-tuning');
            app.BaseModelPathEditField.Value=base_model_path;

            %% check the configs file
            reg_str= 'training_configs.npy';
            if ~exist(fullfile(base_model_path,reg_str),'file')
                errordlg('No config file of base model found. Please reload');
            else
                app.BaseModelPathEditField.Value=fullfile(base_model_path,reg_str);
            end
            
        end

        % Button pushed function: new_sample_loading
        function new_sample_loadingButtonPushed(app, event)
            new_sample_path = uigetdir('./Data','Select the path of new sample for fine-tuning');
            app.NewSamplePathEditField.Value=new_sample_path;

            if (exist(fullfile(new_sample_path,'WF'),'dir') || exist(fullfile(new_sample_path,'wf'),'dir'))
%                     (exist(fullfile(new_sample_path,'LF'),'dir') || exist(fullfile(new_sample_path,'lf'),'dir'))
                ;
            else
                errordlg('No WF//LF folder found. Please reload the path or reformat the folder name');
            end
            %% info

        end

        % Button pushed function: StartFinetuningButton
        function StartFinetuningButtonPushed(app, event)
            
            
            data_root_path = app.NewSamplePathEditField.Value;
            base_mode_config_path= app.BaseModelPathEditField.Value;
            
            fine_tune_settings = {};
            fine_tune_settings.new_model_name=app.LabelEditField_2.Value;
            fine_tune_settings.new_data_path = data_root_path;
            fine_tune_settings.base_mode_config_path= base_mode_config_path;
            fine_tune_settings.gpu_idx= app.Fine_tuning_GPU_IDX.Value;
            fine_tune_settings.projection_range= str2double(app.ProjectionRangeEditField.Value);
%             ft_setting_path = sprintf('./logging/finetune_settings_%s.mat',app.LabelEditField_2.Value);
            ft_setting_path = sprintf('./logging/fine_tune_settings.mat');
            save(ft_setting_path,'fine_tune_settings','-v7.3');
            
            
            %% start fine tuning
            python_config = 'python_interp_dir.mat';
            interp_path= load(python_config);
            python_path=interp_path.interp_path;
%             ft_gpu_idx = app.Fine_tuning_GPU_IDX.Value;
            cmd_str=sprintf('%s %s &',python_path,'./DL/train_finetune.py');
            f=msgbox('strat fine_tuning');
            system(cmd_str)
            close(f);           

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NetworkFinetuningPanel = uipanel(app.UIFigure);
            app.NetworkFinetuningPanel.Title = '4. Network Fine-tuning';
            app.TabGroup4 = uitabgroup(app.NetworkFinetuningPanel);
            app.GeneralTab_4 = uitab(app.TabGroup4);
            app.GeneralTab_4.Title = 'General';
            app.base_model_loading = uibutton(app.GeneralTab_4, 'push');
            app.base_model_loading.ButtonPushedFcn = createCallbackFcn(app, @base_model_loadingButtonPushed, true);
            app.base_model_loading.Text = '...';
            app.new_sample_loading = uibutton(app.GeneralTab_4, 'push');
            app.new_sample_loading.ButtonPushedFcn = createCallbackFcn(app, @new_sample_loadingButtonPushed, true);
            app.new_sample_loading.Text = '...';
            app.StartFinetuningButton = uibutton(app.GeneralTab_4, 'push');
            app.StartFinetuningButton.ButtonPushedFcn = createCallbackFcn(app, @StartFinetuningButtonPushed, true);
            app.StartFinetuningButton.Text = 'Start Fine-tuning';
            app.LabelEditField_2Label = uilabel(app.GeneralTab_4);
            app.LabelEditField_2 = uieditfield(app.GeneralTab_4, 'text');
            app.LabelEditField_2.Value = 'my_fintune_model';
            app.NewSamplePathEditFieldLabel = uilabel(app.GeneralTab_4);
            app.NewSamplePathEditFieldLabel.Text = 'New Sample Path ';
            app.NewSamplePathEditField = uieditfield(app.GeneralTab_4, 'text');
            app.BaseModelPathEditFieldLabel = uilabel(app.GeneralTab_4);
            app.BaseModelPathEditFieldLabel.Text = 'Base Model Path';
            app.BaseModelPathEditField = uieditfield(app.GeneralTab_4, 'text');
            app.AdvancedTab_4 = uitab(app.TabGroup4);
            app.AdvancedTab_4.Title = 'Advanced';
            app.GPU_IDXEditFieldLabel_2 = uilabel(app.AdvancedTab_4);
            app.GPU_IDXEditFieldLabel_2.Text = 'GPU_IDX';
            app.Fine_tuning_GPU_IDX = uieditfield(app.AdvancedTab_4, 'numeric');
            app.ProjectionRangeEditFieldLabel = uilabel(app.AdvancedTab_4);
            app.ProjectionRangeEditFieldLabel.Text = 'Projection  Range';
            app.ProjectionRangeEditField = uieditfield(app.AdvancedTab_4, 'text');
            app.ProjectionRangeEditField.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FineTuning

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end