classdef NetworkTraining < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NetworkTrainingPanel          matlab.ui.container.Panel
        TabGroup2                     matlab.ui.container.TabGroup
        GeneralTab_2                  matlab.ui.container.Tab
        LoadTrainingData              matlab.ui.control.Button
        TrainingDatafolderEditField   matlab.ui.control.EditField
        StartTrainingButton           matlab.ui.control.Button
        TrainingDataConfigLabel       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        LabelEditField                matlab.ui.control.EditField
        LabelEditFieldLabel           matlab.ui.control.Label
        AdvancedTab_2                 matlab.ui.container.Tab
        Ckpt_epochEditField           matlab.ui.control.NumericEditField
        Ckpt_epochEditFieldLabel      matlab.ui.control.Label
        NetworkSettingsLabel          matlab.ui.control.Label
        PreTrainCheckBox              matlab.ui.control.CheckBox
        Recon_GPUEditField            matlab.ui.control.NumericEditField
        Recon_GPUEditFieldLabel       matlab.ui.control.Label
        ViewSR_GPUEditField           matlab.ui.control.NumericEditField
        ViewSR_GPUEditFieldLabel      matlab.ui.control.Label
        DenoiseGPUEditField           matlab.ui.control.NumericEditField
        DenoiseGPUEditFieldLabel      matlab.ui.control.Label
        PreTrainReconCheckBox         matlab.ui.control.CheckBox
        PreTrainViewSRCheckBox        matlab.ui.control.CheckBox
        PreTrainDenoiseCheckBox       matlab.ui.control.CheckBox
        OpenTrainingconfigfileButton  matlab.ui.control.Button
        GPUIdxEditField               matlab.ui.control.NumericEditField
        GPUIdxEditFieldLabel          matlab.ui.control.Label
    end


    properties (Access = private)
        Text_str
    end

    methods (Access = public)

        function  outPut_str(app,str)
            print_str = sprintf('%s\n', str);
            app.Text_str=horzcat(app.Text_str,print_str);
            app.StatusTextArea.Value = app.Text_str;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Network Training';
            addpath ./utils/
            addpath ./DL/
            addpath ./logging/

%             %% check interpreter path
%             if ~exist('./logging/python_interp_dir.mat','file')
%                    errordlg('No python interpreter found. Please load its path in "Edit"');
%                    pause(1);
%                    close(app.UIFigure);
%             end

        end

        % Button pushed function: OpenTrainingconfigfileButton
        function OpenTrainingconfigfileButtonPushed(app, event)
%             [cfg_name,cfg_path] = uigetfile('*.py','Select the config file of VCD','MultiSelect','off','./DL');
%             file_path=fullfile(cfg_path,cfg_name);
            file_path = './DL/config.py';
            try
                [status, cmdout]= system(sprintf('notepad %s',file_path));
            catch 
                errordlg('No config file was found');
            end
            
        end

        % Button pushed function: StartTrainingButton
        function StartTrainingButtonPushed(app, event)
            addpath ./utils/jsonlab-2.0/
            json_file=fullfile(app.TrainingDatafolderEditField.Value,'Data_details.json');
            img_settings= loadjson(json_file);
            img_settings=img_settings{1};
            patch_size=img_settings.patch_size;

            %% get parameters from panel
            traingpairs_settings = {};
            traingpairs_settings.label = app.LabelEditField.Value;
            traingpairs_settings.gpu_idx=app.GPUIdxEditField.Value;
            traingpairs_settings.patch_size= patch_size;
            traingpairs_settings.json_file_path= json_file;
            traingpairs_settings.pretrain_flag=app.PreTrainCheckBox.Value;
            task_flag= [app.PreTrainDenoiseCheckBox.Value,app.PreTrainViewSRCheckBox.Value,app.PreTrainReconCheckBox.Value];
            traingpairs_settings.pretrain_flag_list=task_flag;
            logs_name= sprintf('./logging/TrainSettings_%s.mat',app.LabelEditField.Value);
            if exist(logs_name,'file')
                delete(logs_name);
                save(logs_name,'traingpairs_settings','-v7.3');
            else
                save(logs_name,'traingpairs_settings','-v7.3');
            end
            pause(1); %wait saving
            python_config = 'python_interp_dir.mat';
            interp_path= load(python_config);
            python_path=interp_path.interp_path;

            de_flag_file = sprintf('./logging/preDe_finish_%s.txt',app.LabelEditField.Value);
            sr_flag_file = sprintf('./logging/preSR_finish_%s.txt',app.LabelEditField.Value);
            vcd_flag_file = sprintf('./logging/preVCD_finish_%s.txt',app.LabelEditField.Value);
            try
                delete(de_flag_file);
                delete(sr_flag_file);
                delete(vcd_flag_file);
            end

            %% 
%             app.StartTrainingButton.Text = '[!] Network Training';

            %% pretraining
            if app.PreTrainCheckBox.Value
                task_number= app.PreTrainViewSRCheckBox.Value+app.PreTrainDenoiseCheckBox.Value+app.PreTrainReconCheckBox.Value;
                if task_number~=0
                    task_collcet=cell(3,2);
                    task_collcet{1,1}=app.DenoiseGPUEditField.Value;
                    task_collcet{1,2}=sprintf('%s %s -g %d -cfg %s &',python_path,'./DL/pretrainers/preTrain_denoise.py',app.DenoiseGPUEditField.Value, logs_name);
                    task_collcet{2,1}=app.ViewSR_GPUEditField.Value;
                    task_collcet{2,2}=sprintf('%s %s -g %d -cfg %s &',python_path,'./DL/pretrainers/preTrain_ViewSR.py',app.ViewSR_GPUEditField.Value, logs_name);
                    task_collcet{3,1}=app.Recon_GPUEditField.Value;
                    task_collcet{3,2}=sprintf('%s %s -g %d -cfg %s &',python_path,'./DL/pretrainers/preTrain_VCD.py',app.Recon_GPUEditField.Value, logs_name);
                    % task_number= app.PreTrainViewSRCheckBox.Value+app.PreTrainDenoiseCheckBox.Value+app.PreTrainReconCheckBox.Value;
                    % task_flag= [app.PreTrainDenoiseCheckBox.Value,app.PreTrainViewSRCheckBox.Value,app.PreTrainReconCheckBox.Value];
                    real_task=task_collcet(task_flag,:);
                    task_number=size(real_task,1);
                    gpu_list=[];
                    for ii=1:task_number
                        gpu_list=[gpu_list,real_task{ii,1}]; %#ok<*AGROW>
                    end

                    physial_list=unique(gpu_list);
                    %%
                    for g_idx = 1:length(physial_list)
                        aa=find(gpu_list==physial_list(g_idx));
                        if length(aa)==1
                            cmd_str=real_task{aa,2};
                            system(cmd_str);
                            disp(cmd_str);
                            gpu_list(aa)=-1;
                        end

                    end

                    task_seq=find(gpu_list~=-1);
                    for seq_idx = 1:length(task_seq)
                        task_idx=task_seq(seq_idx);
                        cmd_str=real_task{task_idx,2};
                        cmd_str=strip(cmd_str,'&');
                        system(cmd_str);
                    end
                    %% training

                    while ~(sum(double([exist(de_flag_file,'file')~=0,exist(sr_flag_file,'file')~=0,exist(vcd_flag_file,'file')~=0
                            ]).*task_flag)==sum(task_flag))
%                           app.StatusTextArea.Value='Pre-Training Network';
%                           outPut_str(app,'~>Pre-Training Network');
                    end
                    pause(2);
                    delete(de_flag_file);
                    delete(sr_flag_file);
                    delete(vcd_flag_file);
                    cmd_str=sprintf('%s %s -g %d -cfg %s',python_path,'./DL/train.py',app.GPUIdxEditField.Value,logs_name);
%                     outPut_str(app,sprintf('~>strat training on GPU: %d',app.GPUIdxEditField.Value));
                    system(cmd_str)
                else
                    reload_ckpt= app.Ckpt_epochEditField.Value;
                    cmd_str=sprintf('%s %s -g %d -c %d -cfg %s &',python_path,'./DL/train.py',app.GPUIdxEditField.Value,reload_ckpt,logs_name);
                    %  msgbox(sprintf('strat training on GPU: %d',app.GPUIdxEditField.Value));
%                     app.StatusEditField.Value=sprintf('strat training on GPU: %d',app.GPUIdxEditField.Value);
%                     outPut_str(app,sprintf('~>strat training on GPU: %d',app.GPUIdxEditField.Value));
                    system(cmd_str)
                end

            else
                %% diret training
                reload_ckpt= app.Ckpt_epochEditField.Value;
                cmd_str=sprintf('%s %s -g %d -c %d -cfg %s &',python_path,'./DL/train.py',app.GPUIdxEditField.Value,reload_ckpt,logs_name);
%                 cmd_str=sprintf('%s %s -c %d &',python_path,'./DL/train.py',reload_ckpt);
                %  msgbox(sprintf('strat training on GPU: %d',app.GPUIdxEditField.Value));
                %  app.StatusEditField.Value=sprintf('strat training on GPU: %d',app.GPUIdxEditField.Value);
%                 outPut_str(app,sprintf('~>strat training on GPU: %d',app.GPUIdxEditField.Value));
                system(cmd_str)
            end
%             app.StartTrainingButton.Text = '[!] Finish';
        end

        % Button pushed function: LoadTrainingData
        function LoadTrainingDataPushed(app, event)

            train_data_path=uigetdir('./Data','Choosing the dataset dir (e.g., ./Data/my_data)');
            json_file=fullfile(train_data_path,'Data_details.json');
            if ~exist(json_file,'file')
                errordlg('Dont find json')
            else
                app.TrainingDatafolderEditField.Value=train_data_path;
            end
            %             [data_name,data_path]=uigetfile('*.json','Select the config file of Training Data','MultiSelect','off','./Data/S01_TrainingData');
        end

        % Value changed function: PreTrainDenoiseCheckBox
        function PreTrainDenoiseCheckBoxValueChanged(app, event)
            value = app.PreTrainDenoiseCheckBox.Value;
            if value
                app.DenoiseGPUEditFieldLabel.Visible=1;
                app.DenoiseGPUEditField.Visible=1;
            else
                app.DenoiseGPUEditFieldLabel.Visible=0;
                app.DenoiseGPUEditField.Visible=0;
            end
        end

        % Value changed function: PreTrainViewSRCheckBox
        function PreTrainViewSRCheckBoxValueChanged(app, event)
            value = app.PreTrainViewSRCheckBox.Value;
            if value
                app.ViewSR_GPUEditFieldLabel.Visible=1;
                app.ViewSR_GPUEditField.Visible=1;
            else
                app.ViewSR_GPUEditFieldLabel.Visible=0;
                app.ViewSR_GPUEditField.Visible=0;
            end
        end

        % Value changed function: PreTrainReconCheckBox
        function PreTrainReconCheckBoxValueChanged(app, event)
            value = app.PreTrainReconCheckBox.Value;
            if value
                app.Recon_GPUEditFieldLabel.Visible=1;
                app.Recon_GPUEditField.Visible=1;
            else
                app.Recon_GPUEditFieldLabel.Visible=0;
                app.Recon_GPUEditField.Visible=0;
            end
        end

        % Value changed function: PreTrainCheckBox
        function PreTrainCheckBoxValueChanged(app, event)
            value = app.PreTrainCheckBox.Value;
            if value
                app.PreTrainReconCheckBox.Visible=1;
                app.PreTrainDenoiseCheckBox.Visible=1;
                app.PreTrainViewSRCheckBox.Visible=1;
            else
                app.PreTrainReconCheckBox.Visible=0;
                app.PreTrainDenoiseCheckBox.Visible=0;
                app.PreTrainViewSRCheckBox.Visible=0;

                app.PreTrainReconCheckBox.Value=0;
                app.PreTrainDenoiseCheckBox.Value=0;
                app.PreTrainViewSRCheckBox.Value=0;


                app.Recon_GPUEditFieldLabel.Visible=0;
                app.Recon_GPUEditField.Visible=0;
                app.DenoiseGPUEditFieldLabel.Visible=0;
                app.DenoiseGPUEditField.Visible=0;

                app.ViewSR_GPUEditFieldLabel.Visible=0;
                app.ViewSR_GPUEditField.Visible=0;


            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NetworkTrainingPanel = uipanel(app.UIFigure);
            app.NetworkTrainingPanel.Title = '2. Network Training';
            app.TabGroup2 = uitabgroup(app.NetworkTrainingPanel);
            app.GeneralTab_2 = uitab(app.TabGroup2);
            app.GeneralTab_2.Title = 'General';
            app.LabelEditFieldLabel = uilabel(app.GeneralTab_2);
            app.LabelEditField = uieditfield(app.GeneralTab_2, 'text');
            app.LabelEditField.Value = 'mymodel';
            app.Label_6 = uilabel(app.GeneralTab_2);
            app.Label_6.Text = '';
            app.TrainingDataConfigLabel = uilabel(app.GeneralTab_2);
            app.TrainingDataConfigLabel.Text = 'TrainingData folder';
            app.StartTrainingButton = uibutton(app.GeneralTab_2, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingButtonPushed, true);
            app.StartTrainingButton.Text = 'Start Training';
            app.TrainingDatafolderEditField = uieditfield(app.GeneralTab_2, 'text');
            app.TrainingDatafolderEditField.Value = 'None';
            app.LoadTrainingData = uibutton(app.GeneralTab_2, 'push');
            app.LoadTrainingData.ButtonPushedFcn = createCallbackFcn(app, @LoadTrainingDataPushed, true);
            app.LoadTrainingData.Text = '...';
            app.AdvancedTab_2 = uitab(app.TabGroup2);
            app.AdvancedTab_2.Title = 'Advanced';
            app.GPUIdxEditFieldLabel = uilabel(app.AdvancedTab_2);
            app.GPUIdxEditFieldLabel.Text = 'GPU Idx';
            app.GPUIdxEditField = uieditfield(app.AdvancedTab_2, 'numeric');
            app.OpenTrainingconfigfileButton = uibutton(app.AdvancedTab_2, 'push');
            app.OpenTrainingconfigfileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenTrainingconfigfileButtonPushed, true);
            app.OpenTrainingconfigfileButton.Text = 'Open Training config file';
            app.PreTrainDenoiseCheckBox = uicheckbox(app.AdvancedTab_2);
            app.PreTrainDenoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @PreTrainDenoiseCheckBoxValueChanged, true);
            app.PreTrainDenoiseCheckBox.Visible = 'off';
            app.PreTrainDenoiseCheckBox.Text = 'PreTrainDenoise';
            app.PreTrainViewSRCheckBox = uicheckbox(app.AdvancedTab_2);
            app.PreTrainViewSRCheckBox.ValueChangedFcn = createCallbackFcn(app, @PreTrainViewSRCheckBoxValueChanged, true);
            app.PreTrainViewSRCheckBox.Visible = 'off';
            app.PreTrainViewSRCheckBox.Text = 'PreTrainViewSR';
            app.PreTrainReconCheckBox = uicheckbox(app.AdvancedTab_2);
            app.PreTrainReconCheckBox.ValueChangedFcn = createCallbackFcn(app, @PreTrainReconCheckBoxValueChanged, true);
            app.PreTrainReconCheckBox.Visible = 'off';
            app.PreTrainReconCheckBox.Text = 'PreTrainRecon';
            app.DenoiseGPUEditFieldLabel = uilabel(app.AdvancedTab_2);
            app.DenoiseGPUEditFieldLabel.Visible = 'off';
            app.DenoiseGPUEditFieldLabel.Text = 'DenoiseGPU';
            app.DenoiseGPUEditField = uieditfield(app.AdvancedTab_2, 'numeric');
            app.DenoiseGPUEditField.Visible = 'off';
            app.ViewSR_GPUEditFieldLabel = uilabel(app.AdvancedTab_2);
            app.ViewSR_GPUEditFieldLabel.Visible = 'off';
            app.ViewSR_GPUEditFieldLabel.Text = 'ViewSR_GPU';
            app.ViewSR_GPUEditField = uieditfield(app.AdvancedTab_2, 'numeric');
            app.ViewSR_GPUEditField.Visible = 'off';
            app.Recon_GPUEditFieldLabel = uilabel(app.AdvancedTab_2);
            app.Recon_GPUEditFieldLabel.Visible = 'off';
            app.Recon_GPUEditFieldLabel.Text = 'Recon_GPU';
            app.Recon_GPUEditField = uieditfield(app.AdvancedTab_2, 'numeric');
            app.Recon_GPUEditField.Visible = 'off';
            app.PreTrainCheckBox = uicheckbox(app.AdvancedTab_2);
            app.PreTrainCheckBox.ValueChangedFcn = createCallbackFcn(app, @PreTrainCheckBoxValueChanged, true);
            app.PreTrainCheckBox.Text = 'PreTrain';
            app.NetworkSettingsLabel = uilabel(app.AdvancedTab_2);
            app.NetworkSettingsLabel.Text = ' Network Settings';
            app.Ckpt_epochEditFieldLabel = uilabel(app.AdvancedTab_2);
            app.Ckpt_epochEditFieldLabel.Text = 'Ckpt_epoch';
            app.Ckpt_epochEditField = uieditfield(app.AdvancedTab_2, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NetworkTraining

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end