classdef NetworkValidation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NetworkValidationPanel         matlab.ui.container.Panel
        TabGroup3                      matlab.ui.container.TabGroup
        GeneralTab_3                   matlab.ui.container.Tab
        CheckpointFolderTextArea       matlab.ui.control.TextArea
        CheckpointPathLabel            matlab.ui.control.Label
        ValidationDataFolderTextArea   matlab.ui.control.TextArea
        ValidationDataFolderTextAreaLabel  matlab.ui.control.Label
        Validation_ckpt_loading_Button_3  matlab.ui.control.Button
        Validation_img_loading_Button_2  matlab.ui.control.Button
        StartValidationButton          matlab.ui.control.Button
        AdvancedTab_3                  matlab.ui.container.Tab
        GammaEditField                 matlab.ui.control.NumericEditField
        GammaEditFieldLabel            matlab.ui.control.Label
        ValidationEpochEditField       matlab.ui.control.EditField
        ValidationEpochEditFieldLabel  matlab.ui.control.Label
        Val_GPU_IDXEditField           matlab.ui.control.NumericEditField
        GPU_IDXEditFieldLabel          matlab.ui.control.Label
    end


    properties (Access = private)
        Text_str
    end

    methods (Access = public)

        function  outPut_str(app,str)
            print_str = sprintf('%s\n', str);
            app.Text_str=horzcat(app.Text_str,print_str);
            app.StatusTextArea.Value = app.Text_str;
        end
    end
    
    methods (Access = private)
            function setPromptFcn(app,cmd_str)
               % Prevent overlapping reentry due to prompt replacement
              persistent inProgress
              if isempty(inProgress)
                inProgress = 1;  %#ok unused
              else
                return;
              end
              try
                % *** Prompt modification code goes here ***
                [py_status,cmdout]=system(cmd_str);
                jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
                jCmdWin = jDesktop.getClient('Command Window');
                jTextArea = jCmdWin.getComponent(0).getViewport.getView;
                cwText = char(jTextArea.getText);
%                 app.TextArea.Value = cwText;
                outPut_str(app,cwText);
                % force prompt-change callback to fizzle-out...
                pause(0.02);
              catch
                % Never mind - ignore errors...
              end
              % Enable new callbacks now that the prompt has been modified
              inProgress = [];
             
            end  % setPromptFcn
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Network Validation';
            addpath ./source/psf/
            addpath ./utils/
            addpath ./DL/

            %% add initialization
%             try
%                 global jTextArea
%                 jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
%                 jCmdWin = jDesktop.getClient('Command Window');
%                 jTextArea = jCmdWin.getComponent(0).getViewport.getView;
% %                 set(jTextArea,'CaretUpdateCallback',@app.setPromptFcn)
%             catch
%                 warndlg('fatal error');
%             end

        end

        % Button pushed function: Validation_img_loading_Button_2
        function Validation_img_loading_Button_2Pushed(app, event)
            %             global img_structure
            raw_data_path = uigetdir('./example','Select Input Raw Data');

            if  ~ischar(raw_data_path) 
                ;
            elseif ~exist(raw_data_path,"dir")
                    ;
            else
                app.ValidationDataFolderTextArea.Value=raw_data_path;
            end

        end

        % Button pushed function: Validation_ckpt_loading_Button_3
        function Validation_ckpt_loading_Button_3Pushed(app, event)
            %             [ckpt_name,ckpt_path] = uigetfile('*.npz;*.pth','Select checkpint file','MultiSelect','on','./DL');
            ckpt_path = uigetdir('./DL/checkpoint','Select CKPT path');
            
            if  ~ischar(ckpt_path) 
                    ;
            elseif ~exist(ckpt_path,"dir")
                    ;
            else
                app.CheckpointFolderTextArea.Value=ckpt_path;
            end
           
            %             app.ValidationDataFolderTextArea.Value=fullfile(ckpt_path,ckpt_name);
            %% package
        end

        % Button pushed function: StartValidationButton
        function StartValidationButtonPushed(app, event)


            ckpt_path=app.CheckpointFolderTextArea.Value;
            data_path=app.ValidationDataFolderTextArea.Value;
            path_str = strsplit(ckpt_path{1},'\');
            prefix =path_str{end};
            train_config_path=fullfile(pwd,sprintf('./DL/checkpoint/%s/training_configs.npy',prefix));
            if ~exist(train_config_path,'file')
                errordlg('dont find train config npy');
            end

            validaiton_settings = {};
            validaiton_settings.prefix = prefix;
            validaiton_settings.train_config_path= train_config_path;
            validaiton_settings.data_path= data_path;
            validaiton_settings.eval_ckpt= app.ValidationEpochEditField.Value;
            validaiton_settings.img_gamma= app.GammaEditField.Value;
            save('./logging/validaiton_settings.mat','validaiton_settings','-v7.3');


            %% validation
            python_config = './logging/python_interp_dir.mat';
            interp_path= load(python_config);
            python_path=interp_path.interp_path;
            %             cmd_str=sprintf('cd /d %s',pwd);
            %             system(cmd_str)
            val_gpu_idx = app.Val_GPU_IDXEditField.Value;
            cmd_str=sprintf('%s %s -g %d &',python_path,'./DL/eval.py',val_gpu_idx);
%             cmd_str=sprintf('%s %s -g %d',python_path,'./DL/eval.py',val_gpu_idx);
%             f=waitbar(0,'Waiting the model inference (check the console of Matlab)');
            [py_status,cmdout]=system(cmd_str);
%             app.setPromptFcn(cmd_str);
%             ;
%             outPut_str(app,'~>Pre-Training Network');
%             msg = sprintf("Reconstruction Finished");
%             waitbar(1,f,msg);
%             pause(1.5);
%             close(f);


        end

        % Callback function
        function ClearButtonPushed(app, event)
            app.StatusTextArea.Value = "~> ";
            app.Text_str = app.StatusTextArea.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NetworkValidationPanel = uipanel(app.UIFigure);
            app.NetworkValidationPanel.Title = '3. Network Validation';
            app.TabGroup3 = uitabgroup(app.NetworkValidationPanel);
            app.GeneralTab_3 = uitab(app.TabGroup3);
            app.GeneralTab_3.Title = 'General';
            app.StartValidationButton = uibutton(app.GeneralTab_3, 'push');
            app.StartValidationButton.ButtonPushedFcn = createCallbackFcn(app, @StartValidationButtonPushed, true);
            app.StartValidationButton.Text = 'Start Validation';
            app.Validation_img_loading_Button_2 = uibutton(app.GeneralTab_3, 'push');
            app.Validation_img_loading_Button_2.ButtonPushedFcn = createCallbackFcn(app, @Validation_img_loading_Button_2Pushed, true);
            app.Validation_img_loading_Button_2.Text = '...';
            app.Validation_ckpt_loading_Button_3 = uibutton(app.GeneralTab_3, 'push');
            app.Validation_ckpt_loading_Button_3.ButtonPushedFcn = createCallbackFcn(app, @Validation_ckpt_loading_Button_3Pushed, true);
            app.Validation_ckpt_loading_Button_3.Text = '...';
            app.ValidationDataFolderTextAreaLabel = uilabel(app.GeneralTab_3);
            app.ValidationDataFolderTextAreaLabel.Text = 'Validation Data Folder';
            app.ValidationDataFolderTextArea = uitextarea(app.GeneralTab_3);
            app.ValidationDataFolderTextArea.Value = {'None'};
            app.CheckpointPathLabel = uilabel(app.GeneralTab_3);
            app.CheckpointPathLabel.Text = 'Checkpoint Folder';
            app.CheckpointFolderTextArea = uitextarea(app.GeneralTab_3);
            app.CheckpointFolderTextArea.Value = {'None'};
            app.AdvancedTab_3 = uitab(app.TabGroup3);
            app.AdvancedTab_3.Title = 'Advanced';
            app.GPU_IDXEditFieldLabel = uilabel(app.AdvancedTab_3);
            app.GPU_IDXEditFieldLabel.Text = 'GPU_IDX';
            app.Val_GPU_IDXEditField = uieditfield(app.AdvancedTab_3, 'numeric');
            app.ValidationEpochEditFieldLabel = uilabel(app.AdvancedTab_3);
            app.ValidationEpochEditFieldLabel.Text = 'Validation Epoch';
            app.ValidationEpochEditField = uieditfield(app.AdvancedTab_3, 'text');
            app.ValidationEpochEditField.Value = 'best';
            app.GammaEditFieldLabel = uilabel(app.AdvancedTab_3);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.AdvancedTab_3, 'numeric');
            app.GammaEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NetworkValidation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end