classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        EditMenu                 matlab.ui.container.Menu
        SystemConsoleMenu        matlab.ui.container.Menu
        PythonInterpreterMenu    matlab.ui.container.Menu
        HelpMenu                 matlab.ui.container.Menu
        HomePageMenu             matlab.ui.container.Menu
        AboutMenu                matlab.ui.container.Menu
        Label                    matlab.ui.control.Label
        FinetuningButton         matlab.ui.control.Button
        NetworkValidationButton  matlab.ui.control.Button
        NetworkTrainingButton    matlab.ui.control.Button
        DataGenerationButton     matlab.ui.control.Button
    end


    properties (Access = private)
        Text_str
    end

    methods (Access = public)

        function  outPut_str(app,str)
            print_str = sprintf('%s\n', str);
            app.Text_str=horzcat(app.Text_str,print_str);
            app.StatusTextArea.Value = app.Text_str;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Alpha-Net Package';
            addpath ./source//manual_source/
            addpath ./source/psf/
            addpath ./utils/
            addpath ./DL/


            %% initialization
            logging_path = './logging';
            if ~exist(logging_path,'dir')
                mkdir(logging_path);
            end

            Data_path = './Data';
            if ~exist(Data_path,'dir')
                mkdir(Data_path);
            end


        end

        % Menu selected function: HomePageMenu
        function HomePageMenuSelected(app, event)
            url = 'https://github.com/feilab-hust/Alpha-LFM';
            web(url)
        end

        % Menu selected function: SystemConsoleMenu
        function SystemConsoleMenuSelected(app, event)
            [status, cmdout]= system('cmd &');
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            fig = uifigure;
%             hlink = uihyperlink(fig);
%             hlink.URL = "https://www.mathworks.com";
%             hlink.Text = "VCD 2.0 home page";
%             hlink.Tooltip = hlink.URL;
%             hlink.Position(3:4) = [150 22];
            fig.Position = [0 0 512 512];
            %                 fig.title
            im = uiimage(fig,'Position',[0 0 512 512]);
            im.ScaleMethod = 'scaledown';
            im.ImageSource = './source/manual_source/ABOUT.png';
%                 message = {sprintf('VCD 2.0 Package (version 1.0.0 beta)\n Author: Lanxin Zhu, Jiahao Sun, Chengqiang Yi and contributors \n https://github.com/CQ-Yi/VCD-2.0')};
%                 f = msgbox(message);
        end

        % Menu selected function: PythonInterpreterMenu
        function PythonInterpreterMenuSelected(app, event)
            python_config = './logging/python_interp_dir.mat';
            if ~exist(python_config,'file')
                [python_interp,iterp_dir]=uigetfile('*.exe','Select the Python Interpreter','MultiSelect','off');
                interp_path= fullfile(iterp_dir,python_interp);
                if exist(interp_path,'file')
                    save(python_config,'interp_path');
                    message = {sprintf('new python_interp setting: %s',interp_path)};
                    f = msgbox(message);
                else
                    f = msgbox('Please reload the python interpreter');
                end
%                 pause(2)
%                 close(f);
            else
                interp_path= load(python_config);
                interp_path = interp_path.interp_path;
                if exist(interp_path,'file')
                    message = {sprintf('load python interpreter from: %s',interp_path)};
                    f = msgbox(message);
                else
                    f = msgbox('Please reload the python interpreter');
                    delete(python_config);
                end
%                 pause(2)
%                 close(f);
            end
        end

        % Button pushed function: DataGenerationButton
        function DataGenerationButtonPushed(app, event)
            DataPreparation;
        end

        % Button pushed function: NetworkTrainingButton
        function NetworkTrainingButtonPushed(app, event)
 
            if ~exist('./logging/python_interp_dir.mat','file')
                   errordlg('No python interpreter found. Please load its path in "Edit"');
                   pause(1);
                   close();
            else
                 interp_path = load('./logging/python_interp_dir.mat');
                 interp_path = interp_path.interp_path;
                 assert (contains(interp_path,'python.exe'),'invalid path of python interpreter');
                 NetworkTraining;
            end
        end

        % Button pushed function: NetworkValidationButton
        function NetworkValidationButtonPushed(app, event)
            if ~exist('./logging/python_interp_dir.mat','file')
                   errordlg('No python interpreter found. Please load its path in "Edit"');
                   pause(1);
                   close();
            else
                interp_path = load('./logging/python_interp_dir.mat');
                interp_path = interp_path.interp_path;
                assert (contains(interp_path,'python.exe'),'invalid path of python interpreter');
                 NetworkValidation;
            end
        end

        % Button pushed function: FinetuningButton
        function FinetuningButtonPushed(app, event)
            if ~exist('./logging/python_interp_dir.mat','file')
                errordlg('No python interpreter found. Please load its path in "Edit"');
                pause(1);
                close();
            else
                interp_path = load('./logging/python_interp_dir.mat');
                interp_path = interp_path.interp_path;
                assert (contains(interp_path,'python.exe'),'invalid path of python interpreter');
                FineTuning;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.SystemConsoleMenu = uimenu(app.EditMenu);
            app.SystemConsoleMenu.MenuSelectedFcn = createCallbackFcn(app, @SystemConsoleMenuSelected, true);
            app.SystemConsoleMenu.Text = 'System Console';
            app.PythonInterpreterMenu = uimenu(app.EditMenu);
            app.PythonInterpreterMenu.MenuSelectedFcn = createCallbackFcn(app, @PythonInterpreterMenuSelected, true);
            app.PythonInterpreterMenu.Text = 'Python Interpreter';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.HomePageMenu = uimenu(app.HelpMenu);
            app.HomePageMenu.MenuSelectedFcn = createCallbackFcn(app, @HomePageMenuSelected, true);
            app.HomePageMenu.Separator = 'on';
            app.HomePageMenu.Text = 'HomePage';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.DataGenerationButton = uibutton(app.UIFigure, 'push');
            app.DataGenerationButton.ButtonPushedFcn = createCallbackFcn(app, @DataGenerationButtonPushed, true);
            app.DataGenerationButton.Text = 'Data Generation';
            app.NetworkTrainingButton = uibutton(app.UIFigure, 'push');
            app.NetworkTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkTrainingButtonPushed, true);
            app.NetworkTrainingButton.Text = 'Network Training';
            app.NetworkValidationButton = uibutton(app.UIFigure, 'push');
            app.NetworkValidationButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkValidationButtonPushed, true);
            app.NetworkValidationButton.Text = 'Network Validation';
            app.FinetuningButton = uibutton(app.UIFigure, 'push');
            app.FinetuningButton.ButtonPushedFcn = createCallbackFcn(app, @FinetuningButtonPushed, true);
            app.FinetuningButton.Text = 'Fine-tuning';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Alpha-Net  for super-resolution 3D reconstruction ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end